classdef ARbot_Sim_Interface_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConfigurationParametersPanel   matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        EnvironmentalTab               matlab.ui.container.Tab
        WidthEditField_3Label          matlab.ui.control.Label
        WidthEditField_3               matlab.ui.control.NumericEditField
        ConveyorBeltLabel              matlab.ui.control.Label
        RobotOriginLabel               matlab.ui.control.Label
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        BinRelativeRobotLabel          matlab.ui.control.Label
        XEditField_2Label              matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_2                   matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ZEditField_2                   matlab.ui.control.NumericEditField
        SpeedEditFieldLabel            matlab.ui.control.Label
        SpeedEditField                 matlab.ui.control.NumericEditField
        DistanceEditFieldLabel         matlab.ui.control.Label
        DistanceEditField              matlab.ui.control.NumericEditField
        DistanceBetweenRobotandCameraLabel  matlab.ui.control.Label
        TargetsTab                     matlab.ui.container.Tab
        TargetsLabel                   matlab.ui.control.Label
        SpawnRateSliderLabel           matlab.ui.control.Label
        SpawnRateSlider                matlab.ui.control.Slider
        PicksMinuteLabel               matlab.ui.control.Label
        DimensionWidthButtonGroup      matlab.ui.container.ButtonGroup
        RandomDefaultButton            matlab.ui.control.RadioButton
        SmallButton                    matlab.ui.control.RadioButton
        MediumButton                   matlab.ui.control.RadioButton
        LargeButton                    matlab.ui.control.RadioButton
        DimensionLengthButtonGroup     matlab.ui.container.ButtonGroup
        RandomDefaultButton_2          matlab.ui.control.RadioButton
        SmallButton_2                  matlab.ui.control.RadioButton
        MediumButton_2                 matlab.ui.control.RadioButton
        LargeButton_2                  matlab.ui.control.RadioButton
        SpawnLocationDifficultyButtonGroup  matlab.ui.container.ButtonGroup
        EasyButton                     matlab.ui.control.RadioButton
        MediumButton_3                 matlab.ui.control.RadioButton
        HardButton                     matlab.ui.control.RadioButton
        PerformanceTab                 matlab.ui.container.Tab
        MaximumValuesLabel             matlab.ui.control.Label
        VelocityLabel                  matlab.ui.control.Label
        Joint1EditFieldLabel           matlab.ui.control.Label
        Joint1EditField                matlab.ui.control.NumericEditField
        Joint2EditFieldLabel           matlab.ui.control.Label
        Joint2EditField                matlab.ui.control.NumericEditField
        Joint3EditFieldLabel           matlab.ui.control.Label
        Joint3EditField                matlab.ui.control.NumericEditField
        Joint4EditFieldLabel           matlab.ui.control.Label
        Joint4EditField                matlab.ui.control.NumericEditField
        Joint1EditField_2              matlab.ui.control.NumericEditField
        Joint2EditField_2              matlab.ui.control.NumericEditField
        Joint3EditField_2              matlab.ui.control.NumericEditField
        Joint4EditField_2              matlab.ui.control.NumericEditField
        Joint1EditField_3              matlab.ui.control.NumericEditField
        Joint2EditField_3              matlab.ui.control.NumericEditField
        Joint3EditField_3              matlab.ui.control.NumericEditField
        Joint4EditField_3              matlab.ui.control.NumericEditField
        AccelerationLabel              matlab.ui.control.Label
        TorqueLabel                    matlab.ui.control.Label
        CameraFrameRateEditFieldLabel  matlab.ui.control.Label
        CameraFrameRateEditField       matlab.ui.control.NumericEditField
        CameraLabel                    matlab.ui.control.Label
        PathsTab                       matlab.ui.container.Tab
        URDFEditFieldLabel             matlab.ui.control.Label
        URDFEditField                  matlab.ui.control.EditField
        AddpathstolocalfiledirectoriesLabel  matlab.ui.control.Label
        CurrentFolderEditFieldLabel    matlab.ui.control.Label
        CurrentFolderEditField         matlab.ui.control.EditField
        VideoFeedEditFieldLabel        matlab.ui.control.Label
        VideoFeedEditField             matlab.ui.control.EditField
        SimulinkModelEditFieldLabel    matlab.ui.control.Label
        SimulinkModelEditField         matlab.ui.control.EditField
        LoadButton                     matlab.ui.control.Button
        PerformanceMetricsPanel        matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        LiveMonitorTab                 matlab.ui.container.Tab
        Joint1EditField_4Label         matlab.ui.control.Label
        Joint1EditField_4              matlab.ui.control.NumericEditField
        Joint2EditField_4Label         matlab.ui.control.Label
        Joint2EditField_4              matlab.ui.control.NumericEditField
        Joint3EditField_4Label         matlab.ui.control.Label
        Joint3EditField_4              matlab.ui.control.NumericEditField
        Joint4EditField_4Label         matlab.ui.control.Label
        Joint4EditField_4              matlab.ui.control.NumericEditField
        EndEffectorEditFieldLabel      matlab.ui.control.Label
        EndEffectorEditField           matlab.ui.control.NumericEditField
        PositionLabel                  matlab.ui.control.Label
        EndEffectorEditField_2         matlab.ui.control.NumericEditField
        EndEffectorEditField_3         matlab.ui.control.NumericEditField
        TargetAquiredLampLabel         matlab.ui.control.Label
        TargetAquiredLamp              matlab.ui.control.Lamp
        ExecutingTrajectoryLampLabel   matlab.ui.control.Label
        ExecutingTrajectoryLamp        matlab.ui.control.Lamp
        StateLabel                     matlab.ui.control.Label
        ScanningLampLabel              matlab.ui.control.Label
        ScanningLamp                   matlab.ui.control.Lamp
        TimeElapsedEditFieldLabel      matlab.ui.control.Label
        TimeElapsedEditField           matlab.ui.control.NumericEditField
        SortedEditFieldLabel           matlab.ui.control.Label
        SortedEditField                matlab.ui.control.NumericEditField
        DetectedEditFieldLabel         matlab.ui.control.Label
        DetectedEditField              matlab.ui.control.NumericEditField
        Joint1GaugeLabel               matlab.ui.control.Label
        Joint1Gauge                    matlab.ui.control.Gauge
        MeasuredasaofMaxValueLabel     matlab.ui.control.Label
        TorqueLabel_2                  matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        ReportsTab                     matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        StartButton                    matlab.ui.control.StateButton
        StopButton                     matlab.ui.control.StateButton
        ResetButton                    matlab.ui.control.Button
        GenerateReportButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        startup = 'None';
        file = 'None';
        box_offsets_x; % x and y offsets for the boxes
        box_offsets_y;
        box_dim_w;
        box_dim_l;
    end
    
    methods (Access = public)
        function update_gauge(app, num)
            app.Joint1Gauge.Value = num;
            x1_data = get_param('app_designer_model/Scope14', 'Torque_N-m');
            x1_time = get_param('app_designer_model/Scope14', 'Time');
            plot(app.UIAxes3, x1_time,x1_data);
        end
    end


    methods (Access = private)
        
        function results = update_x_offsets(app)
            selected = app.SpawnLocationDifficultyButtonGroup.SelectedObject;
            width=1.1225;
            easy_range = [width/3 width*2/3]; % middle third
            med_range = [width/4 width*3/4]; % 25% to 75% of belt
            hard_range = [0 med_range width];
            switch(selected.Text)
                case 'Easy'
                    app.box_offsets_x(:,1) = easy_range(1) + diff(easy_range).*rand(5,1);
                case 'Medium'
                    app.box_offsets_x(:,1) = med_range(1) + diff(med_range).*rand(5,1);
                case 'Hard'
                    random_vector = round(rand(5,1))
                    for i = 1:length(random_vector)
                        if random_vector(i) 
                            app.box_offsets_x(i,1) = hard_range(3) + diff(hard_range(3:4))*rand(1,1);
                        else
                            app.box_offsets_x(i,1) = hard_range(1) + diff(hard_range(1:2))*rand(1,1);
                        end
                    end  
            end
            results = app.box_offsets_x;
        end
        
        function results = update_y_offsets(app)
            spawn_rate = app.SpawnRateSlider.Value;
            spawn_rate = ceil(spawn_rate);
            distance = 60/spawn_rate;
            start_offset = 1; %1 m
            sep_vector = 0:distance:distance*4;
            for i = 1:length(sep_vector)
                app.box_offsets_y(i,1) = start_offset + sep_vector(i);
            end
            results = app.box_offsets_y;
        end
        
        function results = update_width(app)
            selectedButton = app.DimensionWidthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_w(:,1) = width_range(1) + diff(width_range).*rand(5,1);
                case 'Small'
                    app.box_dim_w(:,1) = small_range(1) + diff(small_range).*rand(5,1);
                case 'Medium'
                    app.box_dim_w(:,1) = med_range(1) + diff(med_range).*rand(5,1);
                case 'Large'
                    app.box_dim_w(:,1) = large_range(1) + diff(large_range).*rand(5,1);
            end
            results = app.box_dim_w;
        end
        
        function results = update_length(app)
            selectedButton = app.DimensionLengthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_l(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_l(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_l(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_l(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
            results = app.box_dim_l;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SimulinkModelEditField
        function SimulinkModelEditFieldValueChanged(app, event)
            value = app.SimulinkModelEditField.Value;
            
%             parent = '../AppDesigner';
            environment = '../Environment';
            libraries = '../Libraries';
            appdesign = '../AppDesigner';

%             addpath(genpath(parent));
            addpath(genpath(environment));
            addpath(genpath(libraries));
            addpath(genpath(appdesign));
%             app.startup = sprintf('C:/Users/Clayton Tan/Desktop/ARbot_Simulink/v2beta/Spawn/%s',value);
            app.startup = sprintf('C:/Users/Clayton Tan/Desktop/ARbot_Simulink/AppDesigner/%s',value);
            app.file = value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
%              run(app.startup);
%              pause(20);
%              sim(app.startup);
%              app.EditField.Value = sprintf('Successfully Startup of Simulink Model: %s', app.file);
%              app.EditField_2.Value = 'Report Generated';
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            set_param(gcs, 'SimulationCommand', 'stop');
        end

        % Value changed function: StartButton
        function StartButtonPushed(app, event)
            value = app.StartButton.Value;
            app.box_offsets_x = update_x_offsets(app);
            app.box_offsets_y = update_y_offsets(app);
            box_positions = [(app.box_offsets_x) (app.box_offsets_y)];
            
            app.box_dim_w = update_width(app);
            app.box_dim_l = update_length(app);
            box_dim = [(app.box_dim_w) (app.box_dim_l)];
            disp(box_positions);
            if app.startup == 'None'
                app.startup = '/Users/mcontr/repos/ARbot_Simulink/AppDesigner/app_designer_model.slx';
            end
            if value
                run(app.startup);
                save("user_inputs.mat", 'box_positions', 'box_dim');
                sim(app.startup);
            end
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
%             t= readtable('test.txt');
%             app.UITable.Data = t;
%             t.Properties.VariableNames{1}= 'Max_Accel';
%             t.Properties.VariableNames{2}= 'Max_Vel';
%             t.Properties.VariableNames{3}= 'J1_Tq';
%             t.Properties.VariableNames{4}= 'J2_Tq';
%             t.Properties.VariableNames{5}= 'J3_Tq';
%             t.Properties.VariableNames{6}= 'J4_Tq';
%             t.Properties.VariableNames{7}= 'Current_Pos';
%             t.Properties.VariableNames{8}= 'Sorting_Accuracy';
%             t.Properties.VariableNames{9}= 'Sorting_Speed';
%             app.UITable.ColumnName = t.Properties.VariableNames;
        end

        % Size changed function: PathsTab
        function PathsTabSizeChanged(app, event)
            position = app.PathsTab.Position;
            
        end

        % Value changing function: SimulinkModelEditField
        function SimulinkModelEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Selection changed function: DimensionWidthButtonGroup
        function DimWidth(app, event)
            selectedButton = app.DimensionWidthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_w(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_w(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_w(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_w(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
            
            
        end

        % Selection changed function: DimensionLengthButtonGroup
        function DimLength(app, event)
            selectedButton = app.DimensionLengthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_l(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_l(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_l(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_l(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
        end

        % Value changed function: Joint1EditField_4
        function Joint1EditField_4ValueChanged(app, event)

            
        end

        % Value changed function: SpawnRateSlider
        function SpawnRate(app, event)
            update_y_offsets(app)
            
        end

        % Selection changed function: 
        % SpawnLocationDifficultyButtonGroup
        function SpawnLocation(app, event)
            selectedButton = app.SpawnLocationDifficultyButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConfigurationParametersPanel = uipanel(app.UIFigure);
            app.ConfigurationParametersPanel.Title = 'Configuration Parameters';
            app.TabGroup = uitabgroup(app.ConfigurationParametersPanel);
            app.EnvironmentalTab = uitab(app.TabGroup);
            app.EnvironmentalTab.Title = 'Environmental';
            app.WidthEditField_3Label = uilabel(app.EnvironmentalTab);
            app.WidthEditField_3Label.Text = 'Width';
            app.WidthEditField_3 = uieditfield(app.EnvironmentalTab, 'numeric');
            app.ConveyorBeltLabel = uilabel(app.EnvironmentalTab);
            app.ConveyorBeltLabel.Text = 'Conveyor Belt';
            app.RobotOriginLabel = uilabel(app.EnvironmentalTab);
            app.RobotOriginLabel.Text = 'Robot Origin';
            app.XEditFieldLabel = uilabel(app.EnvironmentalTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EnvironmentalTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.EnvironmentalTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EnvironmentalTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EnvironmentalTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EnvironmentalTab, 'numeric');
            app.BinRelativeRobotLabel = uilabel(app.EnvironmentalTab);
            app.BinRelativeRobotLabel.Text = 'Bin Relative Robot';
            app.XEditField_2Label = uilabel(app.EnvironmentalTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.EnvironmentalTab, 'numeric');
            app.YEditField_2Label = uilabel(app.EnvironmentalTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.EnvironmentalTab, 'numeric');
            app.ZEditField_2Label = uilabel(app.EnvironmentalTab);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.EnvironmentalTab, 'numeric');
            app.SpeedEditFieldLabel = uilabel(app.EnvironmentalTab);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.EnvironmentalTab, 'numeric');
            app.DistanceEditFieldLabel = uilabel(app.EnvironmentalTab);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.EnvironmentalTab, 'numeric');
            app.DistanceBetweenRobotandCameraLabel = uilabel(app.EnvironmentalTab);
            app.DistanceBetweenRobotandCameraLabel.Text = 'Distance Between Robot and Camera';
            app.TargetsTab = uitab(app.TabGroup);
            app.TargetsTab.Title = 'Targets';
            app.TargetsLabel = uilabel(app.TargetsTab);
            app.TargetsLabel.Text = 'Targets ';
            app.SpawnRateSliderLabel = uilabel(app.TargetsTab);
            app.SpawnRateSliderLabel.Text = 'Spawn Rate';
            app.SpawnRateSlider = uislider(app.TargetsTab);
            app.SpawnRateSlider.Limits = [10 30];
            app.SpawnRateSlider.ValueChangedFcn = createCallbackFcn(app, @SpawnRate, true);
            app.SpawnRateSlider.Value = 30;
            app.PicksMinuteLabel = uilabel(app.TargetsTab);
            app.PicksMinuteLabel.Text = 'Picks/Minute';
            app.DimensionWidthButtonGroup = uibuttongroup(app.TargetsTab);
            app.DimensionWidthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DimWidth, true);
            app.DimensionWidthButtonGroup.Title = 'Dimension Width';
            app.RandomDefaultButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.RandomDefaultButton.Text = 'Random (Default)';
            app.RandomDefaultButton.Value = true;
            app.SmallButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.SmallButton.Text = 'Small';
            app.MediumButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.LargeButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.LargeButton.Text = 'Large';
            app.DimensionLengthButtonGroup = uibuttongroup(app.TargetsTab);
            app.DimensionLengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DimLength, true);
            app.DimensionLengthButtonGroup.Title = 'Dimension Length';
            app.RandomDefaultButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.RandomDefaultButton_2.Text = 'Random (Default)';
            app.RandomDefaultButton_2.Value = true;
            app.SmallButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.SmallButton_2.Text = 'Small';
            app.MediumButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.MediumButton_2.Text = 'Medium';
            app.LargeButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.LargeButton_2.Text = 'Large';
            app.SpawnLocationDifficultyButtonGroup = uibuttongroup(app.TargetsTab);
            app.SpawnLocationDifficultyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpawnLocation, true);
            app.SpawnLocationDifficultyButtonGroup.Title = 'Spawn Location Difficulty';
            app.EasyButton = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.EasyButton.Text = 'Easy';
            app.EasyButton.Value = true;
            app.MediumButton_3 = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.MediumButton_3.Text = 'Medium';
            app.HardButton = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.HardButton.Text = 'Hard';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.MaximumValuesLabel = uilabel(app.PerformanceTab);
            app.MaximumValuesLabel.Text = 'Maximum Values';
            app.VelocityLabel = uilabel(app.PerformanceTab);
            app.VelocityLabel.Text = 'Velocity';
            app.Joint1EditFieldLabel = uilabel(app.PerformanceTab);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.PerformanceTab);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.PerformanceTab);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint4EditFieldLabel = uilabel(app.PerformanceTab);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint1EditField_2 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint2EditField_2 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint3EditField_2 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint4EditField_2 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint1EditField_3 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint2EditField_3 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint3EditField_3 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint4EditField_3 = uieditfield(app.PerformanceTab, 'numeric');
            app.AccelerationLabel = uilabel(app.PerformanceTab);
            app.AccelerationLabel.Text = 'Acceleration';
            app.TorqueLabel = uilabel(app.PerformanceTab);
            app.TorqueLabel.Text = 'Torque';
            app.CameraFrameRateEditFieldLabel = uilabel(app.PerformanceTab);
            app.CameraFrameRateEditFieldLabel.Text = 'Camera Frame Rate';
            app.CameraFrameRateEditField = uieditfield(app.PerformanceTab, 'numeric');
            app.CameraLabel = uilabel(app.PerformanceTab);
            app.CameraLabel.Text = 'Camera ';
            app.PathsTab = uitab(app.TabGroup);
            app.PathsTab.SizeChangedFcn = createCallbackFcn(app, @PathsTabSizeChanged, true);
            app.PathsTab.Title = 'Paths';
            app.URDFEditFieldLabel = uilabel(app.PathsTab);
            app.URDFEditFieldLabel.Text = 'URDF';
            app.URDFEditField = uieditfield(app.PathsTab, 'text');
            app.AddpathstolocalfiledirectoriesLabel = uilabel(app.PathsTab);
            app.AddpathstolocalfiledirectoriesLabel.Text = 'Add paths to local file directories';
            app.CurrentFolderEditFieldLabel = uilabel(app.PathsTab);
            app.CurrentFolderEditFieldLabel.Text = 'Current Folder';
            app.CurrentFolderEditField = uieditfield(app.PathsTab, 'text');
            app.VideoFeedEditFieldLabel = uilabel(app.PathsTab);
            app.VideoFeedEditFieldLabel.Text = 'Video Feed';
            app.VideoFeedEditField = uieditfield(app.PathsTab, 'text');
            app.SimulinkModelEditFieldLabel = uilabel(app.PathsTab);
            app.SimulinkModelEditFieldLabel.Text = 'Simulink Model';
            app.SimulinkModelEditField = uieditfield(app.PathsTab, 'text');
            app.SimulinkModelEditField.ValueChangedFcn = createCallbackFcn(app, @SimulinkModelEditFieldValueChanged, true);
            app.SimulinkModelEditField.ValueChangingFcn = createCallbackFcn(app, @SimulinkModelEditFieldValueChanging, true);
            app.LoadButton = uibutton(app.PathsTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PerformanceMetricsPanel = uipanel(app.UIFigure);
            app.PerformanceMetricsPanel.Title = 'Performance Metrics';
            app.TabGroup2 = uitabgroup(app.PerformanceMetricsPanel);
            app.LiveMonitorTab = uitab(app.TabGroup2);
            app.LiveMonitorTab.Title = 'Live Monitor';
            app.Joint1EditField_4Label = uilabel(app.LiveMonitorTab);
            app.Joint1EditField_4Label.Text = 'Joint 1';
            app.Joint1EditField_4 = uieditfield(app.LiveMonitorTab, 'numeric');
            app.Joint1EditField_4.ValueChangedFcn = createCallbackFcn(app, @Joint1EditField_4ValueChanged, true);
            app.Joint2EditField_4Label = uilabel(app.LiveMonitorTab);
            app.Joint2EditField_4Label.Text = 'Joint 2';
            app.Joint2EditField_4 = uieditfield(app.LiveMonitorTab, 'numeric');
            app.Joint3EditField_4Label = uilabel(app.LiveMonitorTab);
            app.Joint3EditField_4Label.Text = 'Joint 3';
            app.Joint3EditField_4 = uieditfield(app.LiveMonitorTab, 'numeric');
            app.Joint4EditField_4Label = uilabel(app.LiveMonitorTab);
            app.Joint4EditField_4Label.Text = 'Joint 4';
            app.Joint4EditField_4 = uieditfield(app.LiveMonitorTab, 'numeric');
            app.EndEffectorEditFieldLabel = uilabel(app.LiveMonitorTab);
            app.EndEffectorEditFieldLabel.Text = 'End Effector';
            app.EndEffectorEditField = uieditfield(app.LiveMonitorTab, 'numeric');
            app.PositionLabel = uilabel(app.LiveMonitorTab);
            app.PositionLabel.Text = 'Position';
            app.EndEffectorEditField_2 = uieditfield(app.LiveMonitorTab, 'numeric');
            app.EndEffectorEditField_3 = uieditfield(app.LiveMonitorTab, 'numeric');
            app.TargetAquiredLampLabel = uilabel(app.LiveMonitorTab);
            app.TargetAquiredLampLabel.Text = 'Target Aquired';
            app.TargetAquiredLamp = uilamp(app.LiveMonitorTab);
            app.ExecutingTrajectoryLampLabel = uilabel(app.LiveMonitorTab);
            app.ExecutingTrajectoryLampLabel.Text = 'Executing Trajectory';
            app.ExecutingTrajectoryLamp = uilamp(app.LiveMonitorTab);
            app.StateLabel = uilabel(app.LiveMonitorTab);
            app.StateLabel.Text = 'State';
            app.ScanningLampLabel = uilabel(app.LiveMonitorTab);
            app.ScanningLampLabel.Text = 'Scanning';
            app.ScanningLamp = uilamp(app.LiveMonitorTab);
            app.TimeElapsedEditFieldLabel = uilabel(app.LiveMonitorTab);
            app.TimeElapsedEditFieldLabel.Text = 'Time Elapsed';
            app.TimeElapsedEditField = uieditfield(app.LiveMonitorTab, 'numeric');
            app.SortedEditFieldLabel = uilabel(app.LiveMonitorTab);
            app.SortedEditFieldLabel.Text = 'Sorted';
            app.SortedEditField = uieditfield(app.LiveMonitorTab, 'numeric');
            app.DetectedEditFieldLabel = uilabel(app.LiveMonitorTab);
            app.DetectedEditFieldLabel.Text = 'Detected';
            app.DetectedEditField = uieditfield(app.LiveMonitorTab, 'numeric');
            app.Joint1GaugeLabel = uilabel(app.LiveMonitorTab);
            app.Joint1GaugeLabel.Text = 'Joint 1';
            app.Joint1Gauge = uigauge(app.LiveMonitorTab, 'circular');
            app.Joint1Gauge.Limits = [-200 200];
            app.Joint1Gauge.MajorTicks = [-200 -100 0 100 200];
            app.MeasuredasaofMaxValueLabel = uilabel(app.LiveMonitorTab);
            app.MeasuredasaofMaxValueLabel.Text = 'Measured as a % of Max Value';
            app.TorqueLabel_2 = uilabel(app.LiveMonitorTab);
            app.TorqueLabel_2.Text = 'Torque';
            app.UIAxes3 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes3, 'End Effector')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3.YTick = [-200 -150 -100 -50 0 50 100 150 200];
            app.UIAxes3.YTickLabel = {'-200'; '-150'; '-100'; '-50'; '0'; '50'; '100'; '150'; '200'};
            app.ReportsTab = uitab(app.TabGroup2);
            app.ReportsTab.Title = 'Reports';
            app.UITable = uitable(app.ReportsTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.GenerateReportButton = uibutton(app.UIFigure, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARbot_Sim_Interface_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end