classdef ARbot_Sim_Interface_v0_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConfigurationParametersPanel  matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        TargetsTab                    matlab.ui.container.Tab
        TargetsLabel                  matlab.ui.control.Label
        SpawnRateSliderLabel          matlab.ui.control.Label
        SpawnRateSlider               matlab.ui.control.Slider
        PicksMinuteLabel              matlab.ui.control.Label
        DimensionWidthButtonGroup     matlab.ui.container.ButtonGroup
        RandomDefaultButton           matlab.ui.control.RadioButton
        SmallButton                   matlab.ui.control.RadioButton
        MediumButton                  matlab.ui.control.RadioButton
        LargeButton                   matlab.ui.control.RadioButton
        DimensionLengthButtonGroup    matlab.ui.container.ButtonGroup
        RandomDefaultButton_2         matlab.ui.control.RadioButton
        SmallButton_2                 matlab.ui.control.RadioButton
        MediumButton_2                matlab.ui.control.RadioButton
        LargeButton_2                 matlab.ui.control.RadioButton
        SpawnLocationDifficultyButtonGroup  matlab.ui.container.ButtonGroup
        EasyButton                    matlab.ui.control.RadioButton
        MediumButton_3                matlab.ui.control.RadioButton
        HardButton                    matlab.ui.control.RadioButton
        BeltSpeedButtonGroup          matlab.ui.container.ButtonGroup
        DefaultButton                 matlab.ui.control.RadioButton
        msButton                      matlab.ui.control.RadioButton
        msButton_2                    matlab.ui.control.RadioButton
        msButton_3                    matlab.ui.control.RadioButton
        msButton_4                    matlab.ui.control.RadioButton
        msButton_5                    matlab.ui.control.RadioButton
        msButton_6                    matlab.ui.control.RadioButton
        msButton_7                    matlab.ui.control.RadioButton
        StartButton                   matlab.ui.control.StateButton
        CatchingTimeButtonGroup       matlab.ui.container.ButtonGroup
        secButton_3                   matlab.ui.control.RadioButton
        secButton                     matlab.ui.control.RadioButton
        secButton_2                   matlab.ui.control.RadioButton
        picksminLabel                 matlab.ui.control.Label
        picksminLabel_2               matlab.ui.control.Label
        picksminLabel_3               matlab.ui.control.Label
    end

    
    properties (Access = private)
        startup = 'None';
        file = 'None';
        box_offsets_x; % x and y offsets for the boxes
        box_offsets_y;
        box_dim_w;
        box_dim_l;
        belt_speed = 1.1295;
        catch_time = 2;
        CPU_time = 0.7;
        % dict{key} = value
        catch_2_sec_dict = py.dict(pyargs('0.5 m/s',0.7,'0.75 m/s',0.7,'1 m/s',0.7,'Default',0.7,'1.25 m/s',0.7,'1.5 m/s',0.7,'1.75 m/s',0.15,'2 m/s',0.15));
        catch_1_sec_dict = py.dict(pyargs('0.5 m/s',0.7,'0.75 m/s',0.7,'1 m/s',0.72,'Default',0.72,'1.25 m/s',0.6,'1.5 m/s',0.4,'1.75 m/s',0.2,'2 m/s',0)); 
        catch_half_sec_dict = py.dict(pyargs('0.5 m/s',0.72,'0.75 m/s',0.815,'1 m/s',0.68,'Default',0.68,'1.25 m/s',0.68,'1.5 m/s',0.36,'1.75 m/s',0.15,'2 m/s',0));
    end
   
    
    methods (Access = private)
        
        function results = update_x_offsets(app)
            selected = app.SpawnLocationDifficultyButtonGroup.SelectedObject;
            width=1.1225;
            easy_range = [width/3 width*2/3]; % middle third
            med_range = [width/4 width*3/4]; % 25% to 75% of belt
            hard_range = [0 med_range width];
            switch(selected.Text)
                case 'Easy'
                    app.box_offsets_x(:,1) = easy_range(1) + diff(easy_range).*rand(5,1);
                case 'Medium'
                    app.box_offsets_x(:,1) = med_range(1) + diff(med_range).*rand(5,1);
                case 'Hard'
                    random_vector = round(rand(5,1))
                    for i = 1:length(random_vector)
                        if random_vector(i) 
                            app.box_offsets_x(i,1) = hard_range(3) + diff(hard_range(3:4))*rand(1,1);
                        else
                            app.box_offsets_x(i,1) = hard_range(1) + diff(hard_range(1:2))*rand(1,1);
                        end
                    end  
            end
            results = app.box_offsets_x;
        end
        
        function results = update_y_offsets(app)
            spawn_rate = app.SpawnRateSlider.Value;
            spawn_rate = ceil(spawn_rate);
            distance = 60/spawn_rate;
            start_offset = 1; %1 m
            speed = app.belt_speed + 0.04
            sep_vector = 0:distance*speed:distance*4*speed;
            for i = 1:length(sep_vector)
                app.box_offsets_y(i,1) = start_offset + sep_vector(i);
            end
            results = app.box_offsets_y;
        end
        
        function results = update_width(app)
            selectedButton = app.DimensionWidthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_w(:,1) = width_range(1) + diff(width_range).*rand(5,1);
                case 'Small'
                    app.box_dim_w(:,1) = small_range(1) + diff(small_range).*rand(5,1);
                case 'Medium'
                    app.box_dim_w(:,1) = med_range(1) + diff(med_range).*rand(5,1);
                case 'Large'
                    app.box_dim_w(:,1) = large_range(1) + diff(large_range).*rand(5,1);
            end
            results = app.box_dim_w;
        end
        
        function results = update_length(app)
            selectedButton = app.DimensionLengthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_l(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_l(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_l(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_l(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
            results = app.box_dim_l;
        end
        
        function speed = update_belt_speed(app)
            selectedButton = app.BeltSpeedButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Default'
                    app.belt_speed = 1.1295;
                case '0.5 m/s'
                    app.belt_speed = 0.5;
                case '0.75 m/s'
                    app.belt_speed = 0.75;
                case '1 m/s'
                    app.belt_speed = 1;
                case '1.25 m/s'
                    app.belt_speed = 1.25;
                case '1.5 m/s'
                    app.belt_speed = 1.5;
                case '1.75 m/s'
                    app.belt_speed = 1.75;
                case '2 m/s'
                    app.belt_speed = 2;
            end
            speed = app.belt_speed;
            
        end
        
        function results = update_catch_time(app)
            selected = app.CatchingTimeButtonGroup.SelectedObject
            switch selected.Text
                case '1/2 sec'
                    results = 0.5;
                case '1 sec'
                    results = 1;
                case '2 sec'
                    results = 2;
            end
            
        end
        
        function results = update_inherent_cpu(app,catching_time,speed)
            switch catching_time
                case 0.5
                    switch speed
                        case '0.5 m/s'
                            results = 0.72
                        case '0.75 m/s'
                            results = 0.815
                        case '1 m/s'
                            results = 0.68
                        case '1.25 m/s'
                            results = 0.68
                        case 'Default'
                            results = 0.68
                        case '1.5 m/s'
                            results = 0.36
                        case '1.75 m/s'
                            results = 0.15
                        case '2 m/s'
                            results = 0
                    end
                case 1
                    switch speed
                        case '0.5 m/s'
                            results = 0.7
                        case '0.75 m/s'
                            results = 0.7
                        case '1 m/s'
                            results = 0.72
                        case 'Default'
                            results = 0.72
                        case '1.25 m/s'
                            results = 0.6
                        case '1.5 m/s'
                            results = 0.4
                        case '1.75 m/s'
                            results = 0.2
                        case '2 m/s'
                            results = 0
                    end
                case 2
                    switch speed
                        case '0.5 m/s'
                            results = 0.7
                        case '0.75 m/s'
                            results = 0.7
                        case '1 m/s'
                            results = 0.7
                        case 'Default'
                            results = 0.7
                        case '1.25 m/s'
                            results = 0.7
                        case '1.5 m/s'
                            results = 0.7
                        case '1.75 m/s'
                            results = 0.15
                        case '2 m/s'
                            results = 0.15
                    end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SimulinkModelEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function LoadButtonPushed(app, event)
            
        end

        % Callback function
        function StopButtonValueChanged(app, event)
            
        end

        % Value changed function: StartButton
        function StartButtonPushed(app, event)
            value = app.StartButton.Value;
            
            app.belt_speed = update_belt_speed(app);
            belt_spd = app.belt_speed;
            max_Catching_Time = update_catch_time(app);
            inherentCPUCost = update_inherent_cpu(app, max_Catching_Time, app.BeltSpeedButtonGroup.SelectedObject.Text);
            
            app.box_offsets_x = update_x_offsets(app);
            app.box_offsets_y = update_y_offsets(app);
            box_positions = [(app.box_offsets_x) (app.box_offsets_y)];
            
            app.box_dim_w = update_width(app);
            app.box_dim_l = update_length(app);
            box_dim = [(app.box_dim_w) (app.box_dim_l)];
            
            app.startup = which('app_designer_model.slx');
            if value
                run(app.startup);
                spawn_location = app.SpawnLocationDifficultyButtonGroup.SelectedObject.Text;
                spawn_rate = app.SpawnRateSlider.Value;
                save("user_inputs.mat", 'box_positions', 'box_dim','belt_spd','inherentCPUCost', 'spawn_location','spawn_rate','max_Catching_Time');
                sim(app.startup);
            end
        end

        % Callback function
        function GenerateReportButtonPushed(app, event)
            
        end

        % Selection changed function: DimensionWidthButtonGroup
        function DimWidth(app, event)
            selectedButton = app.DimensionWidthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_w(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_w(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_w(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_w(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
            
            
        end

        % Selection changed function: DimensionLengthButtonGroup
        function DimLength(app, event)
            selectedButton = app.DimensionLengthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_l(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_l(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_l(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_l(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
        end

        % Value changed function: SpawnRateSlider
        function SpawnRate(app, event)
            app.box_offsets_y = update_y_offsets(app);
            
        end

        % Selection changed function: 
        % SpawnLocationDifficultyButtonGroup
        function SpawnLocation(app, event)
            app.box_offsets_x = update_x_offsets(app);
            
        end

        % Callback function
        function BeltSpeed(app, event)
            app.belt_speed = update_belt_speed(app);
        end

        % Selection changed function: CatchingTimeButtonGroup
        function CatchTime(app, event)
            app.catch_time = update_catch_time(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConfigurationParametersPanel = uipanel(app.UIFigure);
            app.ConfigurationParametersPanel.Title = 'Configuration Parameters';
            app.TabGroup = uitabgroup(app.ConfigurationParametersPanel);
            app.TargetsTab = uitab(app.TabGroup);
            app.TargetsTab.Title = 'Targets';
            app.TargetsLabel = uilabel(app.TargetsTab);
            app.TargetsLabel.Text = 'Targets ';
            app.SpawnRateSliderLabel = uilabel(app.TargetsTab);
            app.SpawnRateSliderLabel.Text = 'Spawn Rate';
            app.SpawnRateSlider = uislider(app.TargetsTab);
            app.SpawnRateSlider.Limits = [30 120];
            app.SpawnRateSlider.ValueChangedFcn = createCallbackFcn(app, @SpawnRate, true);
            app.SpawnRateSlider.Value = 30;
            app.PicksMinuteLabel = uilabel(app.TargetsTab);
            app.PicksMinuteLabel.Text = 'Picks/Minute';
            app.DimensionWidthButtonGroup = uibuttongroup(app.TargetsTab);
            app.DimensionWidthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DimWidth, true);
            app.DimensionWidthButtonGroup.Title = 'Dimension Width';
            app.RandomDefaultButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.RandomDefaultButton.Text = 'Random (Default)';
            app.RandomDefaultButton.Value = true;
            app.SmallButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.SmallButton.Text = 'Small';
            app.MediumButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.LargeButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.LargeButton.Text = 'Large';
            app.DimensionLengthButtonGroup = uibuttongroup(app.TargetsTab);
            app.DimensionLengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DimLength, true);
            app.DimensionLengthButtonGroup.Title = 'Dimension Length';
            app.RandomDefaultButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.RandomDefaultButton_2.Text = 'Random (Default)';
            app.RandomDefaultButton_2.Value = true;
            app.SmallButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.SmallButton_2.Text = 'Small';
            app.MediumButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.MediumButton_2.Text = 'Medium';
            app.LargeButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.LargeButton_2.Text = 'Large';
            app.SpawnLocationDifficultyButtonGroup = uibuttongroup(app.TargetsTab);
            app.SpawnLocationDifficultyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpawnLocation, true);
            app.SpawnLocationDifficultyButtonGroup.Title = 'Spawn Location Difficulty';
            app.EasyButton = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.EasyButton.Text = 'Easy';
            app.EasyButton.Value = true;
            app.MediumButton_3 = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.MediumButton_3.Text = 'Medium';
            app.HardButton = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.HardButton.Text = 'Hard';
            app.BeltSpeedButtonGroup = uibuttongroup(app.TargetsTab);
            app.BeltSpeedButtonGroup.Title = 'Belt Speed';
            app.DefaultButton = uiradiobutton(app.BeltSpeedButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.msButton = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton.Text = '0.5 m/s';
            app.msButton_2 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_2.Text = '0.75 m/s';
            app.msButton_3 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_3.Text = '1 m/s';
            app.msButton_4 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_4.Text = '1.25 m/s';
            app.msButton_5 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_5.Text = '1.5 m/s';
            app.msButton_6 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_6.Text = '1.75 m/s';
            app.msButton_7 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_7.Text = '2 m/s';
            app.StartButton = uibutton(app.TargetsTab, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CatchingTimeButtonGroup = uibuttongroup(app.TargetsTab);
            app.CatchingTimeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CatchTime, true);
            app.CatchingTimeButtonGroup.Title = 'Catching Time';
            app.secButton_3 = uiradiobutton(app.CatchingTimeButtonGroup);
            app.secButton_3.Text = '1/2 sec';
            app.secButton = uiradiobutton(app.CatchingTimeButtonGroup);
            app.secButton.Text = '1 sec';
            app.secButton_2 = uiradiobutton(app.CatchingTimeButtonGroup);
            app.secButton_2.Text = '2 sec';
            app.secButton_2.Value = true;
            app.picksminLabel = uilabel(app.CatchingTimeButtonGroup);
            app.picksminLabel.Text = '120 picks/min';
            app.picksminLabel_2 = uilabel(app.CatchingTimeButtonGroup);
            app.picksminLabel_2.Text = '60 picks/min';
            app.picksminLabel_3 = uilabel(app.CatchingTimeButtonGroup);
            app.picksminLabel_3.Text = '30 picks/min';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARbot_Sim_Interface_v0_input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end