classdef ARbot_Sim_Interface_v0_output < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PerformanceMetricsPanel  matlab.ui.container.Panel
        TabGroup2                matlab.ui.container.TabGroup
        LiveMonitorTab           matlab.ui.container.Tab
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes4_2                matlab.ui.control.UIAxes
        UIAxes4_3                matlab.ui.control.UIAxes
        UIAxes4_4                matlab.ui.control.UIAxes
        Joint1Label              matlab.ui.control.Label
        Joint2Label              matlab.ui.control.Label
        Joint3Label              matlab.ui.control.Label
        Joint4Label              matlab.ui.control.Label
        ReportsTab               matlab.ui.container.Tab
        UITable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        tor1 = [];
        tor2 = [];
        tor3 = [];
        tor4 = [];
        accel1 = [];
        accel2 = [];
        accel3 = [];
        accel4 = [];
        vel1 = [];
        vel2 = [];
        vel3 = [];
        vel4 = [];
        pos1 = [];
        pos2 = [];
        pos3 = [];
        pos4 = [];
        time = [];
        
        data_store = [];        
        path = [];        

    end
    
    methods (Access = public)
        function update_data(app, torq, accel, vel, pos, t, x, belt_spd, spawn_location, spawn_rate)
            app.time(x,1) = [t];
            
            app.tor1(x,1) = [torq(1)];
            app.tor2(x,1) = [torq(2)];
            app.tor3(x,1) = [torq(3)];
            app.tor4(x,1) = [torq(4)];
            
            app.accel1(x,1) = ([accel(1)] * 180)/pi;
            app.accel2(x,1) = ([accel(2)] * 180)/pi;
            app.accel3(x,1) = ([accel(3)] * 180)/pi;
            app.accel4(x,1) = ([accel(4)] * 180)/pi;    
            
            app.vel1(x,1) = ([vel(1)] * 180)/pi;
            app.vel2(x,1) = ([vel(2)] * 180)/pi;
            app.vel3(x,1) = ([vel(3)] * 180)/pi;
            app.vel4(x,1) = ([vel(4)] * 180)/pi;
            
            app.pos1(x,1) = ([pos(1)] * 180)/pi;
            app.pos2(x,1) = ([pos(2)] * 180)/pi;
            app.pos3(x,1) = ([pos(3)] * 180)/pi;
            app.pos4(x,1) = ([pos(4)] * 180)/pi;   

            app.UITable.Data = [app.time, app.tor1, app.tor2, app.tor3, app.tor4,...
                                app.accel1, app.accel2, app.accel3, app.accel4,...
                                app.vel1, app.vel2, app.vel3, app.vel4,...
                                app.pos1, app.pos2, app.pos3, app.pos4];         
                              
            if x==1
                app.data_store = {'Time(s)', 'Torq1(N*m)', 'Torq2(N*m)', 'Torq3(N*m)', 'Tor4(N*m)',...
                                    'Accel1(°/s^2)', 'Accel2(°/s^2)', 'Accel3(°/s^2)', 'Accel4(°/s^2)',...
                                    'Vel1(°/s)', 'Vel2(°/s)', 'Vel3(°/s)', 'Vel4(°/s)',...
                                    'Pos1(°)', 'Pos2(°)', 'Pos3(°)', 'Pos4(°)'};

                app.path = sprintf('/Users/mcontr/repos/ARbot_Simulink/AppDesigner/Reports/%d_%d_%d.csv', spawn_rate,spawn_location, belt_spd);
                T = fopen(app.path,'w');
                fprintf(T,'%s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s\n',app.data_store{1,:});
                fclose(T);
            end
            app.data_store = app.UITable.Data;  
            writematrix(app.data_store, app.path,'WriteMode','append');
                            
            plot(app.UIAxes4, app.time, app.tor1, '-r');
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4, app.time, app.tor2, '-b');
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4, app.time, app.tor3, '-g');
            hold(app.UIAxes4,'on');         
            plot(app.UIAxes4, app.time, app.tor4, '-y');
            hold(app.UIAxes4,'on');   
            
            plot(app.UIAxes4_2, app.time, app.accel1, '-r');
            hold(app.UIAxes4_2,'on');
            plot(app.UIAxes4_2, app.time, app.accel2, '-b');
            hold(app.UIAxes4_2,'on');
            plot(app.UIAxes4_2, app.time, app.accel3, '-g');
            hold(app.UIAxes4_2,'on');         
            plot(app.UIAxes4_2, app.time, app.accel4, '-y');
            hold(app.UIAxes4_2,'on');   
            
            plot(app.UIAxes4_3, app.time, app.vel1, '-r');
            hold(app.UIAxes4_3,'on');
            plot(app.UIAxes4_3, app.time, app.vel2, '-b');
            hold(app.UIAxes4_3,'on');
            plot(app.UIAxes4_3, app.time, app.vel3, '-g');
            hold(app.UIAxes4_3,'on');         
            plot(app.UIAxes4_3, app.time, app.vel4, '-y');
            hold(app.UIAxes4_3,'on');   
            
            plot(app.UIAxes4_4, app.time, app.pos1, '-r');
            hold(app.UIAxes4_4,'on');
            plot(app.UIAxes4_4, app.time, app.pos2, '-b');
            hold(app.UIAxes4_4,'on');
            plot(app.UIAxes4_4, app.time, app.pos3, '-g');
            hold(app.UIAxes4_4,'on');         
            plot(app.UIAxes4_4, app.time, app.pos4, '-y');
            hold(app.UIAxes4_4,'on');                    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SimulinkModelEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function LoadButtonPushed(app, event)
            
        end

        % Callback function
        function StopButtonValueChanged(app, event)
            
        end

        % Callback function
        function StartButtonPushed(app, event)
            
        end

        % Callback function
        function GenerateReportButtonPushed(app, event)
            
        end

        % Callback function
        function DimWidth(app, event)
            
        end

        % Callback function
        function DimLength(app, event)
            
        end

        % Callback function
        function SpawnRate(app, event)

            
        end

        % Callback function
        function SpawnLocation(app, event)

            
        end

        % Callback function
        function BeltSpeed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PerformanceMetricsPanel = uipanel(app.UIFigure);
            app.PerformanceMetricsPanel.Title = 'Performance Metrics';
            app.TabGroup2 = uitabgroup(app.PerformanceMetricsPanel);
            app.LiveMonitorTab = uitab(app.TabGroup2);
            app.LiveMonitorTab.Title = 'Live Monitor';
            app.UIAxes4 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes4, 'Torque vs. Time')
            xlabel(app.UIAxes4, 'Time (sec)')
            ylabel(app.UIAxes4, 'Torque (N*m)')
            app.UIAxes4_2 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes4_2, 'Accel vs. Time')
            xlabel(app.UIAxes4_2, 'Time (sec)')
            ylabel(app.UIAxes4_2, 'Accel (°/s^2)')
            app.UIAxes4_3 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes4_3, 'Vel vs. Time')
            xlabel(app.UIAxes4_3, 'Time (sec)')
            ylabel(app.UIAxes4_3, 'Vel (°/s)')
            app.UIAxes4_4 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes4_4, 'Pos vs. Time')
            xlabel(app.UIAxes4_4, 'Time (sec)')
            ylabel(app.UIAxes4_4, 'Pos (°)')
            app.Joint1Label = uilabel(app.LiveMonitorTab);
            app.Joint1Label.Text = 'Joint 1';
            app.Joint2Label = uilabel(app.LiveMonitorTab);
            app.Joint2Label.Text = 'Joint 2';
            app.Joint3Label = uilabel(app.LiveMonitorTab);
            app.Joint3Label.Text = 'Joint 3';
            app.Joint4Label = uilabel(app.LiveMonitorTab);
            app.Joint4Label.Text = 'Joint 4';
            app.ReportsTab = uitab(app.TabGroup2);
            app.ReportsTab.Title = 'Reports';
            app.UITable = uitable(app.ReportsTab);
            app.UITable.ColumnName = {'Time(s)'; 'Torq1(N*m)'; 'Torq2(N*m)'; 'Torq3(N*m)'; 'Torq4(N*m)'; 'Accel1(°/s^2)'; 'Accel2(°/s^2)'; 'Accel3(°/s^2)'; 'Accel4(°/s^2)'; 'Vel1(°/s)'; 'Vel2(°/s)'; 'Vel3(°/s)'; 'Vel4(°/s)'; 'Pos1(°)'; 'Pos2(°)'; 'Pos3(°)'; 'Pos4(°)'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARbot_Sim_Interface_v0_output

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end