classdef appDesignTest_4dof_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        angle1EditFieldLabel       matlab.ui.control.Label
        angle1                     matlab.ui.control.NumericEditField
        angle2EditFieldLabel       matlab.ui.control.Label
        angle2                     matlab.ui.control.NumericEditField
        angle3EditFieldLabel       matlab.ui.control.Label
        angle3                     matlab.ui.control.NumericEditField
        JointAnglesLabel           matlab.ui.control.Label
        EndEffectorPositionLabel   matlab.ui.control.Label
        PosXLabel                  matlab.ui.control.Label
        PosX                       matlab.ui.control.NumericEditField
        PosYEditFieldLabel         matlab.ui.control.Label
        PosY                       matlab.ui.control.NumericEditField
        PosZEditFieldLabel         matlab.ui.control.Label
        PosZ                       matlab.ui.control.NumericEditField
        ForwardButton              matlab.ui.control.Button
        InverseButton              matlab.ui.control.Button
        angle4Label                matlab.ui.control.Label
        angle4                     matlab.ui.control.NumericEditField
        PhiEditFieldLabel          matlab.ui.control.Label
        Phi                        matlab.ui.control.NumericEditField
        HomeButton                 matlab.ui.control.Button
        angle1SliderLabel          matlab.ui.control.Label
        angle1Slider               matlab.ui.control.Slider
        angle2SliderLabel          matlab.ui.control.Label
        angle2Slider               matlab.ui.control.Slider
        angle3SliderLabel          matlab.ui.control.Label
        angle3Slider               matlab.ui.control.Slider
        angle4SliderLabel          matlab.ui.control.Label
        angle4Slider               matlab.ui.control.Slider
        Traveltimet_fSpinnerLabel  matlab.ui.control.Label
        tf                         matlab.ui.control.Spinner
        AnimateTrajectoryLabel     matlab.ui.control.Label
        animate                    matlab.ui.control.Button
        setStartPoint              matlab.ui.control.Button
        SetViaPoint                matlab.ui.control.Button
        SetFinalPoint              matlab.ui.control.Button
    end

    % Global Variables
    properties (Access = private)
        % Link lengths
        L1=.2;
        L2=.5;
        L3=.4;
        L4=.3;
        th1=0;
        th2=45;
        th3=-45;
        th4=-45;
        px=0;
        py=0;
        pz=0;
        phi=0;
        % Trajectory variables
        traj;
        viaPoint;
        startPoint;
        endPoint;
        t_f;
        
        robot;
        config;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Assign initial values for text boxes           
            app.angle1.Value = app.th1;
            app.angle2.Value = app.th2;
            app.angle3.Value = app.th3;
            app.angle4.Value = app.th4;
            
            % At start up initialize the robotic arm and plot it
%             addpath('C:\Users\logan\Documents\MATLAB\Robotics\dynamic_sim_v1\4dof_v12\urdf');
%             app.robot=importrobot('4dof_v12_3.urdf');
            addpath('C:\Users\logan\Documents\MATLAB\Robotics\Arbot_v1\description\urdf');
            app.robot=importrobot('arbot_v1.urdf');
            axes=show(app.robot);
            app.config = homeConfiguration(app.robot);
            
            % Compute joint torque to hold static position
            % plot the joint torques
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % assume user inputs angles in degrees
            app.th1=app.angle1.Value*pi/180;
            app.th2=app.angle2.Value*pi/180;
            app.th3=app.angle3.Value*pi/180;
            app.th4=app.angle4.Value*pi/180;
            
            % update workspace for simulink model variables
%             assignin('base','q1',app.th1);
%             assignin('base','q2',app.th2);
%             assignin('base','q3',app.th3);
%             assignin('base','q4',app.th4);
            
            % Plot the robot 
            app.config(1).JointPosition = app.th1;
            app.config(2).JointPosition = app.th2;
            app.config(3).JointPosition = app.th3;
            app.config(4).JointPosition = app.th4;
            show(app.robot, app.config)
            % Compute forward kinematic transformation matrix
            p=forwardKineRBT(app.th1, app.th2, app.th3, app.th4);            
            app.px=p(1);
            app.py=p(2);
            app.pz=p(3);
            % Displays the position of end-effector 
            app.PosX.Value=app.px;
            app.PosY.Value=app.py;
            app.PosZ.Value=app.pz;
            % Update Slider values
            app.angle1Slider.Value=app.th1*180/pi;
            app.angle2Slider.Value=app.th2*180/pi;
            app.angle3Slider.Value=app.th3*180/pi;
            app.angle4Slider.Value=app.th4*180/pi;
            
            % Compute joint torque to hold static position
            % plot the joint torques
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            % Grab the desired position from user input
            app.px=app.PosX.Value;
            app.py=app.PosY.Value;
            app.pz=app.PosZ.Value;
            app.phi=app.Phi.Value*pi/180;
            % Compute the joint angles to achieve the new position
            J=inverseKineRBT(app.px,app.py,app.pz,app.phi);
            % Display new joint angles in degrees
            app.angle1.Value = J(1)*180/pi;
            app.angle2.Value = J(2)*180/pi;
            app.angle3.Value = J(3)*180/pi; 
            app.angle4.Value = J(4)*180/pi;
            % Update private angle values
            app.th1=J(1);
            app.th2=J(2);
            app.th3=J(3);
            app.th4=J(4);
            % Compute forward kinematic transformation matrix
            p=forwardKineRBT(app.th1, app.th2, app.th3, app.th4);            
            app.PosX.Value=p(1);
            app.PosY.Value=p(2);
            app.PosZ.Value=p(3);
            % Update robot config
            app.config(1).JointPosition = J(1);
            app.config(2).JointPosition = J(2);
            app.config(3).JointPosition = J(3);
            app.config(4).JointPosition = J(4);
            % Update Slider values
            app.angle1Slider.Value=app.th1*180/pi;
            app.angle2Slider.Value=app.th2*180/pi;
            app.angle3Slider.Value=app.th3*180/pi;
            app.angle4Slider.Value=app.th4*180/pi;
            % Plot robot
            show(app.robot, app.config);
            
            % Compute joint torque to hold static position
            % plot the joint torques
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.angle1.Value=0;
            app.angle2.Value=45;
            app.angle3.Value=-45;
            app.angle4.Value=-45;
            ForwardButtonPushed(app, event);
            
            % Compute joint torque to hold static position
            % plot the joint torques
            
            % update workspace for simulink model variables
%             assignin('base','q1',app.th1);
%             assignin('base','q2',app.th2);
%             assignin('base','q3',app.th3);
%             assignin('base','q4',app.th4);
        end

        % Value changing function: angle1Slider
        function angle1SliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: angle1Slider
        function angle1SliderValueChanged(app, event)
            value = app.angle1Slider.Value;
            % Display new joint angle
            app.angle1.Value=value;
            app.th1=app.angle1.Value*(pi/180);
            % Plot bot
            app.config(1).JointPosition = app.th1;
            app.config(2).JointPosition = app.th2;
            app.config(3).JointPosition = app.th3;
            app.config(4).JointPosition = app.th4;
            show(app.robot, app.config)
            % Compute forward kinematic transformation matrix
            p=forwardKineRBT(app.th1, app.th2, app.th3, app.th4);
            app.px=p(1);
            app.py=p(2);
            app.pz=p(3);
            % Displays the position of end-effector 
            app.PosX.Value=app.px;
            app.PosY.Value=app.py;
            app.PosZ.Value=app.pz;
            
            % Compute joint torque to hold static position
            % plot the joint torques
            
            % update workspace for simulink model variables
            %assignin('base','q1',app.th1);
        end

        % Value changed function: angle2Slider
        function angle2SliderValueChanged(app, event)
            value = app.angle2Slider.Value;
            % Display new joint angle
            app.angle2.Value=value;
            app.th2=app.angle2.Value*(pi/180);
            % Plot bot
            app.config(1).JointPosition = app.th1;
            app.config(2).JointPosition = app.th2;
            app.config(3).JointPosition = app.th3;
            app.config(4).JointPosition = app.th4;
            show(app.robot, app.config)
            % Compute forward kinematic transformation matrix
            p=forwardKineRBT(app.th1, app.th2, app.th3, app.th4);
            app.px=p(1);
            app.py=p(2);
            app.pz=p(3);
            % Displays the position of end-effector 
            app.PosX.Value=app.px;
            app.PosY.Value=app.py;
            app.PosZ.Value=app.pz;
            
            % Compute joint torque to hold static position
            % plot the joint torques
            
            % update workspace for simulink model variables
            %assignin('base','q2',app.th2);
        end

        % Value changed function: angle3Slider
        function angle3SliderValueChanged(app, event)
            value = app.angle3Slider.Value;
            % Display new joint angle
            app.angle3.Value=value;
            app.th3=app.angle3.Value*(pi/180);
            % Plot bot
            app.config(1).JointPosition = app.th1;
            app.config(2).JointPosition = app.th2;
            app.config(3).JointPosition = app.th3;
            app.config(4).JointPosition = app.th4;
            show(app.robot, app.config)
            % Compute forward kinematic transformation matrix
            p=forwardKineRBT(app.th1, app.th2, app.th3, app.th4);
            app.px=p(1);
            app.py=p(2);
            app.pz=p(3);
            % Displays the position of end-effector 
            app.PosX.Value=app.px;
            app.PosY.Value=app.py;
            app.PosZ.Value=app.pz;
            
            % Compute joint torque to hold static position
            % plot the joint torques
            
            % update workspace for simulink model variables
            %assignin('base','q3',app.th3);
        end

        % Value changed function: angle4Slider
        function angle4SliderValueChanged(app, event)
            value = app.angle4Slider.Value;
            % Display new joint angle
            app.angle4.Value=value;
            app.th4=app.angle4.Value*(pi/180);
            % Plot bot
            app.config(1).JointPosition = app.th1;
            app.config(2).JointPosition = app.th2;
            app.config(3).JointPosition = app.th3;
            app.config(4).JointPosition = app.th4;
            show(app.robot, app.config)
            % Compute forward kinematic transformation matrix
            p=forwardKineRBT(app.th1, app.th2, app.th3, app.th4);
            app.px=p(1);
            app.py=p(2);
            app.pz=p(3);
            % Displays the position of end-effector 
            app.PosX.Value=app.px;
            app.PosY.Value=app.py;
            app.PosZ.Value=app.pz;
            
            % Compute joint torque to hold static position
            % plot the joint torques
            
            % update workspace for simulink model variables
            %assignin('base','q4',app.th4);
            
        end

        % Callback function
        function SetStartPointButtonValueChanged(app, event)
            value = app.setStartPoint.Value;
            app.startPoint=[app.th1 app.th2 app.th3 app.th4];
        end

        % Callback function
        function SetViaPointButtonValueChanged(app, event)
            value = app.SetViaPoint.Value;
            app.viaPoint=[app.th1 app.th2 app.th3 app.th4];
        end

        % Callback function
        function SetFinalPointButtonValueChanged(app, event)
            value = app.SetFinalPoint.Value;
            app.endPoint=[app.th1 app.th2 app.th3 app.th4];
        end

        % Value changed function: tf
        function tfValueChanged(app, event)
            value = app.tf.Value;
            app.t_f=value;
        end

        % Callback function
        function animateValueChanged(app, event)
            value = app.animate.Value;
        end

        % Button pushed function: setStartPoint
        function setStartPointButtonPushed(app, event)
            app.startPoint=[app.th1 app.th2 app.th3 app.th4];
        end

        % Button pushed function: SetViaPoint
        function SetViaPointButtonPushed(app, event)
            app.viaPoint=[app.th1 app.th2 app.th3 app.th4];
        end

        % Button pushed function: SetFinalPoint
        function SetFinalPointButtonPushed(app, event)
            app.endPoint=[app.th1 app.th2 app.th3 app.th4];
        end

        % Button pushed function: animate
        function animateButtonPushed(app, event)
            app.t_f=0:app.tf.Value/12:app.tf.Value;
            % Generate the trajectory
            [th, th_dot, th_ddot] = traj6_v2(app.startPoint, app.viaPoint, app.endPoint, app.tf.Value, app.tf.Value/2);
            n=length(th)
            for i=1:n
                app.config(1).JointPosition = th(i,1);
                app.config(2).JointPosition = th(i,2);
                app.config(3).JointPosition = th(i,3);
                app.config(4).JointPosition = th(i,4);
                show(app.robot, app.config);
                tau(i,:) = getJointTorques(app.robot,th(i,:),th_dot(i,:),th_ddot(i,:));
                pause(app.tf.Value/n); % works for slow times but not for fast times
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.angle1EditFieldLabel = uilabel(app.UIFigure);
            app.angle1EditFieldLabel.Text = 'angle 1';
            app.angle1 = uieditfield(app.UIFigure, 'numeric');
            app.angle2EditFieldLabel = uilabel(app.UIFigure);
            app.angle2EditFieldLabel.Text = 'angle 2';
            app.angle2 = uieditfield(app.UIFigure, 'numeric');
            app.angle3EditFieldLabel = uilabel(app.UIFigure);
            app.angle3EditFieldLabel.Text = 'angle 3';
            app.angle3 = uieditfield(app.UIFigure, 'numeric');
            app.JointAnglesLabel = uilabel(app.UIFigure);
            app.JointAnglesLabel.Text = 'Joint Angles';
            app.EndEffectorPositionLabel = uilabel(app.UIFigure);
            app.EndEffectorPositionLabel.Text = 'End Effector Position';
            app.PosXLabel = uilabel(app.UIFigure);
            app.PosXLabel.Text = 'Pos X';
            app.PosX = uieditfield(app.UIFigure, 'numeric');
            app.PosX.Value = 0.55;
            app.PosYEditFieldLabel = uilabel(app.UIFigure);
            app.PosYEditFieldLabel.Text = 'Pos Y';
            app.PosY = uieditfield(app.UIFigure, 'numeric');
            app.PosY.Value = 0.56;
            app.PosZEditFieldLabel = uilabel(app.UIFigure);
            app.PosZEditFieldLabel.Text = 'Pos Z';
            app.PosZ = uieditfield(app.UIFigure, 'numeric');
            app.PosZ.Value = 0.182;
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.angle4Label = uilabel(app.UIFigure);
            app.angle4Label.Text = 'angle 4';
            app.angle4 = uieditfield(app.UIFigure, 'numeric');
            app.PhiEditFieldLabel = uilabel(app.UIFigure);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.Phi = uieditfield(app.UIFigure, 'numeric');
            app.Phi.Value = 90;
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.angle1SliderLabel = uilabel(app.UIFigure);
            app.angle1SliderLabel.Text = 'angle 1';
            app.angle1Slider = uislider(app.UIFigure);
            app.angle1Slider.Limits = [-180 180];
            app.angle1Slider.ValueChangedFcn = createCallbackFcn(app, @angle1SliderValueChanged, true);
            app.angle1Slider.ValueChangingFcn = createCallbackFcn(app, @angle1SliderValueChanging, true);
            app.angle2SliderLabel = uilabel(app.UIFigure);
            app.angle2SliderLabel.Text = 'angle 2';
            app.angle2Slider = uislider(app.UIFigure);
            app.angle2Slider.Limits = [-180 180];
            app.angle2Slider.ValueChangedFcn = createCallbackFcn(app, @angle2SliderValueChanged, true);
            app.angle3SliderLabel = uilabel(app.UIFigure);
            app.angle3SliderLabel.Text = 'angle 3';
            app.angle3Slider = uislider(app.UIFigure);
            app.angle3Slider.Limits = [-180 180];
            app.angle3Slider.ValueChangedFcn = createCallbackFcn(app, @angle3SliderValueChanged, true);
            app.angle4SliderLabel = uilabel(app.UIFigure);
            app.angle4SliderLabel.Text = 'angle 4';
            app.angle4Slider = uislider(app.UIFigure);
            app.angle4Slider.Limits = [-180 180];
            app.angle4Slider.ValueChangedFcn = createCallbackFcn(app, @angle4SliderValueChanged, true);
            app.Traveltimet_fSpinnerLabel = uilabel(app.UIFigure);
            app.Traveltimet_fSpinnerLabel.Text = 'Travel time (t_f)';
            app.tf = uispinner(app.UIFigure);
            app.tf.ValueChangedFcn = createCallbackFcn(app, @tfValueChanged, true);
            app.AnimateTrajectoryLabel = uilabel(app.UIFigure);
            app.AnimateTrajectoryLabel.Text = 'Animate Trajectory';
            app.animate = uibutton(app.UIFigure, 'push');
            app.animate.ButtonPushedFcn = createCallbackFcn(app, @animateButtonPushed, true);
            app.animate.Text = 'Start';
            app.setStartPoint = uibutton(app.UIFigure, 'push');
            app.setStartPoint.ButtonPushedFcn = createCallbackFcn(app, @setStartPointButtonPushed, true);
            app.setStartPoint.Text = 'Set Start Point';
            app.SetViaPoint = uibutton(app.UIFigure, 'push');
            app.SetViaPoint.ButtonPushedFcn = createCallbackFcn(app, @SetViaPointButtonPushed, true);
            app.SetViaPoint.Text = 'Set Via Point';
            app.SetFinalPoint = uibutton(app.UIFigure, 'push');
            app.SetFinalPoint.ButtonPushedFcn = createCallbackFcn(app, @SetFinalPointButtonPushed, true);
            app.SetFinalPoint.Text = 'Set Final Point';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDesignTest_4dof_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end